classdef EntranceModelApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PointofInterestPanel           matlab.ui.container.Panel
        GaindBiEditField               matlab.ui.control.NumericEditField
        GaindBiEditFieldLabel          matlab.ui.control.Label
        ThetadegreesEditField          matlab.ui.control.NumericEditField
        ThetadegreesEditFieldLabel     matlab.ui.control.Label
        PhidegreesEditField            matlab.ui.control.NumericEditField
        PhidegreesEditFieldLabel       matlab.ui.control.Label
        DistancetoTranspondermEditField  matlab.ui.control.NumericEditField
        DistancetoTranspondermEditFieldLabel  matlab.ui.control.Label
        PowerReceiveduWEditField       matlab.ui.control.NumericEditField
        PowerReceiveduWEditFieldLabel  matlab.ui.control.Label
        PoweratPOImWEditField          matlab.ui.control.NumericEditField
        PoweratPOImWEditFieldLabel     matlab.ui.control.Label
        YmEditField                    matlab.ui.control.NumericEditField
        YmEditFieldLabel               matlab.ui.control.Label
        XmEditField                    matlab.ui.control.NumericEditField
        XmEditFieldLabel               matlab.ui.control.Label
        OutputPanel                    matlab.ui.container.Panel
        MinimumTimeInFieldEditField    matlab.ui.control.NumericEditField
        MaximumTimeInFieldEditField    matlab.ui.control.NumericEditField
        TimeinFieldsLabel              matlab.ui.control.Label
        MaxDistancewithin3dBrangemEditField  matlab.ui.control.NumericEditField
        MaxDistancewithin3dBrangemEditFieldLabel  matlab.ui.control.Label
        MaximumAntennaApertureLengthmEditField  matlab.ui.control.NumericEditField
        MaximumAntennaApertureLengthmEditFieldLabel  matlab.ui.control.Label
        HPBWdegreesEditField           matlab.ui.control.NumericEditField
        HPBWdegreesEditFieldLabel      matlab.ui.control.Label
        MinimumPowerReceiveduWEditField  matlab.ui.control.NumericEditField
        MinimumPowerReceiveduWLabel    matlab.ui.control.Label
        FarRangemEditField             matlab.ui.control.NumericEditField
        HorizontalRangemLabel          matlab.ui.control.Label
        CloseRangemEditField           matlab.ui.control.NumericEditField
        InputPanel                     matlab.ui.container.Panel
        Switch                         matlab.ui.control.Switch
        HeightDeltamEditField          matlab.ui.control.NumericEditField
        HeightDeltamEditFieldLabel     matlab.ui.control.Label
        SubjectVelocitykmhrEditField   matlab.ui.control.NumericEditField
        SubjectVelocitykmhrEditFieldLabel  matlab.ui.control.Label
        ComputeButton                  matlab.ui.control.Button
        FarFieldThresholdmEditField    matlab.ui.control.NumericEditField
        FarFieldThresholdmEditFieldLabel  matlab.ui.control.Label
        FrequencyMHzEditField          matlab.ui.control.NumericEditField
        FrequencyMHzEditFieldLabel     matlab.ui.control.Label
        MaximumPowerTransmittedWEditField  matlab.ui.control.NumericEditField
        MaximumPowerTransmittedWEditFieldLabel  matlab.ui.control.Label
        AntennaGaindBiEditField        matlab.ui.control.NumericEditField
        AntennaGaindBiEditFieldLabel   matlab.ui.control.Label
        EntranceWidthmEditField        matlab.ui.control.NumericEditField
        EntranceWidthmEditFieldLabel   matlab.ui.control.Label
        AntennaToEntrancemEditField    matlab.ui.control.NumericEditField
        AntennaToEntrancemEditFieldLabel  matlab.ui.control.Label
        GainAxes                       matlab.ui.control.UIAxes
        UIAxes2D                       matlab.ui.control.UIAxes
        UIAxes3D                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        entranceWidth = 1.5;
        antenna2entrance = 0;
        inputPower = 0.5;
        frequency = 915e6;
        FFThreshold = 0.5;
        subjectVelocity = 5;
        heightDelta = 0;
        
        antennaGainDBI = 0;
        
        hpbwDegrees = 0;
        
        closeRange = 0;
        farRange = 0;
        maximum3dBDistance = 0;
        
        interpolatedGainCurveColor = "yellow";
        projectedGainCurveColor = "blue";
        interpolatedGainPointColor = "kx";
        projectedGainPointColor = "rx";
        
        domain2D = linspace(-1,1);
        a2eLine;
        entranceWLine;
        negativeLine;
        positiveLine;
        FFDomain;
        FFCurve;
        FFLine;
        
        ReceiverXCoordinates = [-0.25 -0.25 0.25 0.25];
        ReceiverYCoordinates = [0 0 0 0];
        ReceiverZCoordinates = [-0.25 0.25 0.25 -0.25];
        
        TagXCoordinates = [-0.05 -0.05 0.05 0.05];
        TagYCoordinates = [0 0 0 0];
        TagZCoordinates = [-0.05 0.05 0.05 -0.05];
        TagColor = "green"
        ReceiverColor = "red";
        
        receiver = 0;
        tag = 0;
    end
    
    methods (Access = private)
        
        function ms = KMHR2MS(~, kmhr)
            ms = kmhr / 3.6;
        end
        
        function plotSetupCurves(app)
            % Plot the setup lines
            cla(app.UIAxes2D) 
            hold(app.UIAxes2D,'on')
            plot(app.UIAxes2D, app.domain2D, app.a2eLine, "DisplayName",'Antenna To Entrance');
            plot(app.UIAxes2D, app.domain2D, app.entranceWLine, "DisplayName",'Entrance Width');
            [~, zeroIndex] = min(abs(app.domain2D));
            plot(app.UIAxes2D, app.domain2D(1:zeroIndex), app.negativeLine, "DisplayName",'3dB Line');
            plot(app.UIAxes2D, app.domain2D(zeroIndex:end), app.positiveLine, "DisplayName",'3dB Line');
            plot(app.UIAxes2D, app.domain2D, app.FFLine,  "DisplayName",'Far-Field Tangent')
            plot(app.UIAxes2D, app.FFDomain, app.FFCurve,  "DisplayName",'Far-Field Radius')
            legend(app.UIAxes2D);
            
            hold(app.UIAxes2D,'off')
        end


        function plotGainCurves(app)
            alpha = app.hpbwDegrees/2;
            x = linspace(-alpha,alpha);
            gainCurve = projectGain(app,x);
            
            %axis(app.GainAxes, [-180 180 0 app.antennaGainDBI]);
            cla(app.GainAxes);
            hold(app.GainAxes,'on')
            plot(app.GainAxes, [alpha 0 -alpha], [app.antennaGainDBI-3 app.antennaGainDBI app.antennaGainDBI-3], "Color",app.interpolatedGainCurveColor, "DisplayName",'Interpolated Gain');
            plot(app.GainAxes, x, gainCurve, "Color", app.projectedGainCurveColor, "DisplayName",'Projected Gain');
            legend(app.GainAxes);
            
            hold(app.GainAxes,'off')
        end
        
        function out = projectGain(app,in)
            alpha = app.hpbwDegrees/2;
            out = 3*cos((pi/2)/deg2rad(alpha) * deg2rad(in)) + (app.antennaGainDBI-3);
        end
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AntennaToEntrancemEditField
        function AntennaToEntrancemEditFieldValueChanged(app, event)
            app.antenna2entrance = app.AntennaToEntrancemEditField.Value;
        end

        % Value changed function: EntranceWidthmEditField
        function EntranceWidthmEditFieldValueChanged(app, event)
            app.entranceWidth = app.EntranceWidthmEditField.Value;
        end

        % Value changed function: AntennaGaindBiEditField
        function AntennaGaindBiEditFieldValueChanged(app, event)
            app.antennaGainDBI = app.AntennaGaindBiEditField.Value;
        end

        % Value changed function: MaximumPowerTransmittedWEditField
        function MaximumPowerTransmittedWEditFieldValueChanged(app, event)
            app.inputPower = app.MaximumPowerTransmittedWEditField.Value;
        end

        % Value changed function: FrequencyMHzEditField
        function FrequencyMHzEditFieldValueChanged(app, event)
            app.frequency = (app.FrequencyMHzEditField.Value) * 10^6;
        end

        % Value changed function: FarFieldThresholdmEditField
        function FarFieldThresholdmEditFieldValueChanged(app, event)
            app.FFThreshold = app.FarFieldThresholdmEditField.Value;
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            % initialize setup
            app.hpbwDegrees = gain2BW(app.antennaGainDBI, app.Switch.Value == "2-beams");
            app.HPBWdegreesEditField.Value = app.hpbwDegrees;
            
            alpha = app.hpbwDegrees / 2;
            alphaRadians = deg2rad(alpha);
            slope = cot(alphaRadians);
            
            plotGainCurves(app);
            
            app.closeRange = app.antenna2entrance / slope;
            app.farRange = (app.entranceWidth + app.antenna2entrance) / slope;
            app.maximum3dBDistance = sqrt((app.entranceWidth + app.antenna2entrance)^2 + app.farRange^2);
            
            app.domain2D = linspace(-app.farRange-1,app.farRange+1);

            app.MaxDistancewithin3dBrangemEditField.Value = app.maximum3dBDistance;
            app.FarRangemEditField.Value = app.farRange;
            app.MaximumTimeInFieldEditField.Value = 2 * app.farRange / KMHR2MS(app, app.subjectVelocity);
            
            % Plot the setup lines                        
            app.a2eLine = app.antenna2entrance * ones(size(app.domain2D));
            app.entranceWLine = (app.entranceWidth + app.antenna2entrance) * ones(size(app.domain2D));
            [~, zeroIndex] = min(abs(app.domain2D));
            app.negativeLine = app.domain2D(1:zeroIndex) * -1 * slope;
            app.positiveLine = app.domain2D(zeroIndex:end) * slope;
                        
            % Plot the Far Field threshold curve
            [app.FFDomain, app.FFCurve] = getFFThresholdCurve(app.domain2D, app.FFThreshold);
            app.FFLine = app.FFThreshold * ones(size(app.domain2D));
            
            app.closeRange = max(app.FFThreshold / slope, app.closeRange);
            app.CloseRangemEditField.Value = app.closeRange;
            app.MinimumTimeInFieldEditField.Value = 2 * app.closeRange / KMHR2MS(app, app.subjectVelocity);
            
            plotSetupCurves(app);
            
            % Calculate power at furthest point
            antennaGain = 10^((app.antennaGainDBI - 3)/10);
            thetaRadians = atan(app.maximum3dBDistance/app.heightDelta);
            distance = sqrt(app.maximum3dBDistance^2 + app.heightDelta^2);
        
            powerAtFurthestPoint = getPowerReceived(app.inputPower, antennaGain, distance, app.frequency, thetaRadians);
            minimumPowerReceived = getPowerReceived(powerAtFurthestPoint, antennaGain, distance, app.frequency, thetaRadians);
            minimumPowerReceiveduW = minimumPowerReceived * 1e6;
            
            app.MinimumPowerReceiveduWEditField.Value = minimumPowerReceiveduW;
            
            app.MaximumAntennaApertureLengthmEditField.Value = getMaximumAperture(app.FFThreshold, app.frequency);
            
            % Plot 3D illustration
            cla(app.UIAxes3D) 
            hold(app.UIAxes3D,'on');
            app.receiver = patch(app.UIAxes3D, app.ReceiverXCoordinates, app.ReceiverYCoordinates, app.ReceiverZCoordinates, app.ReceiverColor);
            app.tag = patch(app.UIAxes3D, app.TagXCoordinates + app.farRange, app.TagYCoordinates + (app.entranceWidth + app.antenna2entrance), app.TagZCoordinates + app.heightDelta, app.TagColor);
            hold(app.UIAxes3D,'off');
        end

        % Button down function: UIAxes2D
        function UIAxes2DButtonDown(app, event)
            cla(app.UIAxes3D) 
            hold(app.UIAxes3D,'on')
            app.UIAxes2D.HandleVisibility = 'callback';
           
            cp = get(app.UIAxes2D,'CurrentPoint');
            x = cp(1,1);
            y = cp(1,2);
            
            app.receiver = patch(app.UIAxes3D, app.ReceiverXCoordinates, app.ReceiverYCoordinates, app.ReceiverZCoordinates, app.ReceiverColor);
            app.tag = patch(app.UIAxes3D, app.TagXCoordinates + x, app.TagYCoordinates + y, app.TagZCoordinates + app.heightDelta, app.TagColor);
            
            hold(app.UIAxes3D,'off');
            app.UIAxes2D.HandleVisibility = 'off';
            
            plotSetupCurves(app);
            hold(app.UIAxes2D,'on');
            plot(app.UIAxes2D, x,y,'r*', "DisplayName",'Point of Interest');
            hold(app.UIAxes2D,'off');
            
            distance = sqrt(x^2 + y^2 +app.heightDelta^2);
            phiRadians = atan(x/y);
            
            thetaRadians = atan(distance/app.heightDelta);
                        
            app.XmEditField.Value = x;
            app.YmEditField.Value = y;
            
            interpolatedGainDBI = interpolateGain(app.antennaGainDBI,deg2rad(app.hpbwDegrees), abs(phiRadians));
            antennaGain = 10^(interpolatedGainDBI/10);
            powerAtPOI = getPowerReceived(app.inputPower, antennaGain, distance, app.frequency,thetaRadians);
            powerReceived = getPowerReceived(powerAtPOI, antennaGain, distance, app.frequency,thetaRadians);
                        
            if (abs(x)<app.FarFieldThresholdmEditField.Value)
                [~,~,idx]=unique(round(abs(app.FFDomain-abs(x))),'stable');
                yMax = app.FFCurve(idx==1);
                if (y<yMax)
                   powerReceived = 0;
                end
            end
            
            plotGainCurves(app);
            hold(app.GainAxes,'on');
            plot(app.GainAxes, rad2deg(phiRadians), interpolatedGainDBI,app.interpolatedGainPointColor, "DisplayName",'POI Interpolated Gain');
            plot(app.GainAxes, rad2deg(phiRadians), projectGain(app,rad2deg(phiRadians)),app.projectedGainPointColor, "DisplayName",'POI Projected Gain');
            hold(app.GainAxes,'off');
            
            
            app.DistancetoTranspondermEditField.Value = distance;
            app.PoweratPOImWEditField.Value = powerAtPOI * 10^3;
            app.PowerReceiveduWEditField.Value = powerReceived * 10^6;
            app.PhidegreesEditField.Value = rad2deg(phiRadians);
            app.ThetadegreesEditField.Value = rad2deg(thetaRadians);
            app.GaindBiEditField.Value = interpolatedGainDBI;
            
            
        end

        % Value changed function: HeightDeltamEditField
        function HeightDeltamEditFieldValueChanged(app, event)
            app.heightDelta = app.HeightDeltamEditField.Value;
        end

        % Value changed function: SubjectVelocitykmhrEditField
        function SubjectVelocitykmhrEditFieldValueChanged(app, event)
            app.subjectVelocity = app.SubjectVelocitykmhrEditField.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes3D = uiaxes(app.UIFigure);
            title(app.UIAxes3D, '3D Illustration')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            zlabel(app.UIAxes3D, 'Z')
            app.UIAxes2D = uiaxes(app.UIFigure);
            title(app.UIAxes2D, 'Entrance Model Illustration')
            xlabel(app.UIAxes2D, 'X (m)')
            ylabel(app.UIAxes2D, 'Y (m)')
            zlabel(app.UIAxes2D, 'Z')
            app.UIAxes2D.ButtonDownFcn = createCallbackFcn(app, @UIAxes2DButtonDown, true);
            app.GainAxes = uiaxes(app.UIFigure);
            title(app.GainAxes, 'Gain vs. Phi')
            xlabel(app.GainAxes, 'Phi (degrees)')
            ylabel(app.GainAxes, 'Gain (dBi)')
            zlabel(app.GainAxes, 'Z')
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.AntennaToEntrancemEditFieldLabel = uilabel(app.InputPanel);
            app.AntennaToEntrancemEditFieldLabel.Text = 'Antenna To Entrance (m)';
            app.AntennaToEntrancemEditField = uieditfield(app.InputPanel, 'numeric');
            app.AntennaToEntrancemEditField.ValueChangedFcn = createCallbackFcn(app, @AntennaToEntrancemEditFieldValueChanged, true);
            app.EntranceWidthmEditFieldLabel = uilabel(app.InputPanel);
            app.EntranceWidthmEditFieldLabel.Text = 'Entrance Width (m)';
            app.EntranceWidthmEditField = uieditfield(app.InputPanel, 'numeric');
            app.EntranceWidthmEditField.ValueChangedFcn = createCallbackFcn(app, @EntranceWidthmEditFieldValueChanged, true);
            app.EntranceWidthmEditField.Value = 1.5;
            app.AntennaGaindBiEditFieldLabel = uilabel(app.InputPanel);
            app.AntennaGaindBiEditFieldLabel.Text = 'Antenna Gain (dBi)';
            app.AntennaGaindBiEditField = uieditfield(app.InputPanel, 'numeric');
            app.AntennaGaindBiEditField.ValueChangedFcn = createCallbackFcn(app, @AntennaGaindBiEditFieldValueChanged, true);
            app.MaximumPowerTransmittedWEditFieldLabel = uilabel(app.InputPanel);
            app.MaximumPowerTransmittedWEditFieldLabel.Text = 'Maximum Power Transmitted (W)';
            app.MaximumPowerTransmittedWEditField = uieditfield(app.InputPanel, 'numeric');
            app.MaximumPowerTransmittedWEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumPowerTransmittedWEditFieldValueChanged, true);
            app.MaximumPowerTransmittedWEditField.Value = 0.5;
            app.FrequencyMHzEditFieldLabel = uilabel(app.InputPanel);
            app.FrequencyMHzEditFieldLabel.Text = 'Frequency (MHz)';
            app.FrequencyMHzEditField = uieditfield(app.InputPanel, 'numeric');
            app.FrequencyMHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyMHzEditFieldValueChanged, true);
            app.FrequencyMHzEditField.Value = 915;
            app.FarFieldThresholdmEditFieldLabel = uilabel(app.InputPanel);
            app.FarFieldThresholdmEditFieldLabel.Text = 'Far Field Threshold (m)';
            app.FarFieldThresholdmEditField = uieditfield(app.InputPanel, 'numeric');
            app.FarFieldThresholdmEditField.ValueChangedFcn = createCallbackFcn(app, @FarFieldThresholdmEditFieldValueChanged, true);
            app.FarFieldThresholdmEditField.Value = 0.5;
            app.ComputeButton = uibutton(app.InputPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.SubjectVelocitykmhrEditFieldLabel = uilabel(app.InputPanel);
            app.SubjectVelocitykmhrEditFieldLabel.Text = 'Subject Velocity (km/hr)';
            app.SubjectVelocitykmhrEditField = uieditfield(app.InputPanel, 'numeric');
            app.SubjectVelocitykmhrEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectVelocitykmhrEditFieldValueChanged, true);
            app.SubjectVelocitykmhrEditField.Value = 5;
            app.HeightDeltamEditFieldLabel = uilabel(app.InputPanel);
            app.HeightDeltamEditFieldLabel.Text = 'Height Delta (m)';
            app.HeightDeltamEditField = uieditfield(app.InputPanel, 'numeric');
            app.HeightDeltamEditField.ValueChangedFcn = createCallbackFcn(app, @HeightDeltamEditFieldValueChanged, true);
            app.Switch = uiswitch(app.InputPanel, 'slider');
            app.Switch.Items = {'1-beam', '2-beams'};
            app.Switch.Value = '1-beam';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.CloseRangemEditField = uieditfield(app.OutputPanel, 'numeric');
            app.HorizontalRangemLabel = uilabel(app.OutputPanel);
            app.HorizontalRangemLabel.Text = '< Horizontal Range (m) <';
            app.FarRangemEditField = uieditfield(app.OutputPanel, 'numeric');
            app.MinimumPowerReceiveduWLabel = uilabel(app.OutputPanel);
            app.MinimumPowerReceiveduWLabel.Text = 'Minimum Power Received (uW)';
            app.MinimumPowerReceiveduWEditField = uieditfield(app.OutputPanel, 'numeric');
            app.HPBWdegreesEditFieldLabel = uilabel(app.OutputPanel);
            app.HPBWdegreesEditFieldLabel.Text = 'HPBW (degrees)';
            app.HPBWdegreesEditField = uieditfield(app.OutputPanel, 'numeric');
            app.MaximumAntennaApertureLengthmEditFieldLabel = uilabel(app.OutputPanel);
            app.MaximumAntennaApertureLengthmEditFieldLabel.Text = 'Maximum Antenna Aperture Length (m)';
            app.MaximumAntennaApertureLengthmEditField = uieditfield(app.OutputPanel, 'numeric');
            app.MaxDistancewithin3dBrangemEditFieldLabel = uilabel(app.OutputPanel);
            app.MaxDistancewithin3dBrangemEditFieldLabel.Text = 'Max Distance within 3dB range (m)';
            app.MaxDistancewithin3dBrangemEditField = uieditfield(app.OutputPanel, 'numeric');
            app.TimeinFieldsLabel = uilabel(app.OutputPanel);
            app.TimeinFieldsLabel.Text = '< Time in Field (s) < ';
            app.MaximumTimeInFieldEditField = uieditfield(app.OutputPanel, 'numeric');
            app.MinimumTimeInFieldEditField = uieditfield(app.OutputPanel, 'numeric');
            app.PointofInterestPanel = uipanel(app.UIFigure);
            app.PointofInterestPanel.Title = 'Point of Interest';
            app.XmEditFieldLabel = uilabel(app.PointofInterestPanel);
            app.XmEditFieldLabel.Text = 'X (m)';
            app.XmEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            app.YmEditFieldLabel = uilabel(app.PointofInterestPanel);
            app.YmEditFieldLabel.Text = 'Y (m)';
            app.YmEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            app.PoweratPOImWEditFieldLabel = uilabel(app.PointofInterestPanel);
            app.PoweratPOImWEditFieldLabel.Text = 'Power at POI (mW)';
            app.PoweratPOImWEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            app.PowerReceiveduWEditFieldLabel = uilabel(app.PointofInterestPanel);
            app.PowerReceiveduWEditFieldLabel.Text = 'Power Received (uW)';
            app.PowerReceiveduWEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            app.DistancetoTranspondermEditFieldLabel = uilabel(app.PointofInterestPanel);
            app.DistancetoTranspondermEditFieldLabel.Text = 'Distance to Transponder (m)';
            app.DistancetoTranspondermEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            app.PhidegreesEditFieldLabel = uilabel(app.PointofInterestPanel);
            app.PhidegreesEditFieldLabel.Text = 'Phi (degrees)';
            app.PhidegreesEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            app.ThetadegreesEditFieldLabel = uilabel(app.PointofInterestPanel);
            app.ThetadegreesEditFieldLabel.Text = 'Theta (degrees)';
            app.ThetadegreesEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            app.GaindBiEditFieldLabel = uilabel(app.PointofInterestPanel);
            app.GaindBiEditFieldLabel.Text = 'Gain (dBi)';
            app.GaindBiEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EntranceModelApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end